classdef interfaccia_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        IntervalloconsideratoPanel    matlab.ui.container.Panel
        FattoremoltiplicativosuperioreEditField  matlab.ui.control.NumericEditField
        FattoremoltiplicativosuperioreLabel  matlab.ui.control.Label
        FattoremoltiplicativoinferioreEditField  matlab.ui.control.NumericEditField
        FattoremoltiplicativoinferioreEditFieldLabel  matlab.ui.control.Label
        NumeroCampioniEditField       matlab.ui.control.NumericEditField
        NumeroCampioniEditFieldLabel  matlab.ui.control.Label
        ValoriDerivatiPanel           matlab.ui.container.Panel
        FrequenzaDiRisonanzaValue     matlab.ui.control.Label
        ImpedenzaAcusticaDirezionezValue  matlab.ui.control.Label
        ImpedenzaAcusticaPiezoValue   matlab.ui.control.Label
        VelocitaDiPropagazioneValue   matlab.ui.control.Label
        CapacitaStaticaValue          matlab.ui.control.Label
        AreapiezoValue                matlab.ui.control.Label
        CostantebetaValue             matlab.ui.control.Label
        FrequenzadirisonanzaHzLabel   matlab.ui.control.Label
        ImpedenzaacusticadellelementoindirezionezkgsLabel  matlab.ui.control.Label
        Impedenzaacusticapiezokgm2sLabel  matlab.ui.control.Label
        VelocitdipropagazionemsLabel  matlab.ui.control.Label
        CapacitstaticaCVLabel         matlab.ui.control.Label
        Areapiezoelettricom2Label     matlab.ui.control.Label
        CostantebetaVmCLabel          matlab.ui.control.Label
        GeometriadellapiastraPanel    matlab.ui.container.Panel
        SpessoremEditField            matlab.ui.control.NumericEditField
        SpessoremLabel                matlab.ui.control.Label
        LarghezzamEditField           matlab.ui.control.NumericEditField
        LarghezzamEditFieldLabel      matlab.ui.control.Label
        LunghezzamEditField           matlab.ui.control.NumericEditField
        LunghezzamEditFieldLabel      matlab.ui.control.Label
        SceltaPiezoelettricoPanel     matlab.ui.container.Panel
        DensitaDiMassaValue           matlab.ui.control.Label
        CostantePiezoelettricaValue   matlab.ui.control.Label
        CostantehValue                matlab.ui.control.Label
        CostanteElasticaValue         matlab.ui.control.Label
        DensitaDiMassaLabel           matlab.ui.control.Label
        CostantePiezoelettricaLabel   matlab.ui.control.Label
        CostantehVmLabel              matlab.ui.control.Label
        CostanteElasticaLabel         matlab.ui.control.Label
        PiezoelettricoDropDown        matlab.ui.control.DropDown
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        UnaceramicaTab                matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        StudiosingolomaterialeTab     matlab.ui.container.Tab
        ConfrontotramaterialiTab      matlab.ui.container.Tab
        DueCeramicheTab               matlab.ui.container.Tab
        TrasduttorebandalargaTab      matlab.ui.container.Tab
        LangevinTab                   matlab.ui.container.Tab
        LangevinconconcentratoreTab   matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        piezoTable = readtable('Ferroperm MatData.xls');
        rho_acqua = 997;
        v_acqua = 1484;
        rho_aria = 1.225;
        v_aria = 343;
        z_backing = 7e6;
        z_aria;
        z_acqua;
        Zel = 1e7
    end
    
    methods (Access = private)

        function initImpedenzeMateriali(app) 
            app.z_acqua = app.rho_acqua * app.v_acqua; 
            app.z_aria = app.rho_aria * app.v_aria; 
        end
        
        function aggiornaPiezo(app, piezoelettrico)
            app.CostanteElasticaValue.Text = strcat(num2str(app.piezoTable(56, piezoelettrico).(1), '%.3g'), ' ');          
            app.CostantehValue.Text = strcat(num2str(app.piezoTable(23, piezoelettrico).(1), '%.3g'), ' ' );
            app.CostantePiezoelettricaValue.Text = strcat(num2str(app.piezoTable(20, piezoelettrico).(1), '%.3g'), ' ');
            app.DensitaDiMassaValue.Text = strcat(num2str(app.piezoTable(33, piezoelettrico).(1), '%.3g'), ' ');
        end

        function calcolaValoriDerivati(app)
            beta_val = str2double(app.CostantehValue.Text) / str2double(app.CostantePiezoelettricaValue.Text);
            app.CostantebetaValue.Text = strcat(num2str(beta_val, '%.3g'), ' ');

            area_val = app.LarghezzamEditField.Value * app.LunghezzamEditField.Value;
            app.AreapiezoValue.Text = strcat(num2str(area_val, '%.2e'), ' ');

            capacita_statica_val = str2double(app.AreapiezoValue.Text)/(str2double(app.CostantebetaValue.Text) * app.SpessoremEditField.Value);
            app.CapacitaStaticaValue.Text = strcat(num2str(capacita_statica_val, '%.3g'), ' ');

            velocita_value = sqrt(str2double(app.CostanteElasticaValue.Text) / str2double(app.DensitaDiMassaValue.Text));
            app.VelocitaDiPropagazioneValue.Text = strcat(num2str(velocita_value, '%.3g'), ' ');

            impedenza_acustica_piezo_value = str2double(app.VelocitaDiPropagazioneValue.Text) * str2double(app.DensitaDiMassaValue.Text);
            app.ImpedenzaAcusticaPiezoValue.Text = strcat(num2str(impedenza_acustica_piezo_value, '%.3g'), ' ');

            impedenza_acustica_direzionez_value = str2double(app.AreapiezoValue.Text) * str2double(app.ImpedenzaAcusticaPiezoValue.Text);
            app.ImpedenzaAcusticaDirezionezValue.Text = strcat(num2str(impedenza_acustica_direzionez_value, '%.3g'), ' ');

            freq_risonanza_value = str2double(app.VelocitaDiPropagazioneValue.Text)/(2 * app.SpessoremEditField.Value);
            app.FrequenzaDiRisonanzaValue.Text = strcat(num2str(freq_risonanza_value, '%.3g'), ' ');
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initImpedenzeMateriali(app);
            addpath('../condivise');
            properties = app.piezoTable.Properties.VariableNames;
            propertiesConsidered = [properties(3:10), properties(15:end)];
            app.PiezoelettricoDropDown.Items = propertiesConsidered;
            aggiornaPiezo(app, 'Pz21');
            calcolaValoriDerivati(app);
        end

        % Value changed function: PiezoelettricoDropDown
        function PiezoelettricoDropDownValueChanged(app, event)
            value = app.PiezoelettricoDropDown.Value;
            aggiornaPiezo(app, value);
            calcolaValoriDerivati(app);
        end

        % Value changed function: SpessoremEditField
        function SpessoremEditFieldValueChanged(app, event)
            calcolaValoriDerivati(app);
        end

        % Value changed function: LarghezzamEditField
        function LarghezzamEditFieldValueChanged(app, event)
            calcolaValoriDerivati(app);
        end

        % Value changed function: LunghezzamEditField
        function LunghezzamEditFieldValueChanged(app, event)
            calcolaValoriDerivati(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {791, 791};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {312, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SceltaPiezoelettricoPanel = uipanel(app.LeftPanel);
            app.SceltaPiezoelettricoPanel.Title = 'Scelta Piezoelettrico';
            app.PiezoelettricoDropDown = uidropdown(app.SceltaPiezoelettricoPanel);
            app.PiezoelettricoDropDown.ValueChangedFcn = createCallbackFcn(app, @PiezoelettricoDropDownValueChanged, true);
            app.CostanteElasticaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostanteElasticaLabel.Text = 'Costante elastica [N/m^2]:';
            app.CostantehVmLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantehVmLabel.Text = 'Costante h [V/m]:';
            app.CostantePiezoelettricaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantePiezoelettricaLabel.Text = 'Costante piezoelettrica [C/m^2] :';
            app.DensitaDiMassaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.DensitaDiMassaLabel.Text = 'Densità di massa [kg/m^3]:';
            app.CostanteElasticaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostanteElasticaValue.Text = '';
            app.CostantehValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantehValue.Text = '';
            app.CostantePiezoelettricaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantePiezoelettricaValue.Text = '';
            app.DensitaDiMassaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.DensitaDiMassaValue.Text = '';
            app.GeometriadellapiastraPanel = uipanel(app.LeftPanel);
            app.GeometriadellapiastraPanel.Title = 'Geometria della piastra';
            app.LunghezzamEditFieldLabel = uilabel(app.GeometriadellapiastraPanel);
            app.LunghezzamEditFieldLabel.Text = 'Lunghezza [m]: ';
            app.LunghezzamEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.LunghezzamEditField.Limits = [0.0001 100];
            app.LunghezzamEditField.ValueChangedFcn = createCallbackFcn(app, @LunghezzamEditFieldValueChanged, true);
            app.LunghezzamEditField.Value = 0.01;
            app.LarghezzamEditFieldLabel = uilabel(app.GeometriadellapiastraPanel);
            app.LarghezzamEditFieldLabel.Text = 'Larghezza [m]:';
            app.LarghezzamEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.LarghezzamEditField.Limits = [0.0001 100];
            app.LarghezzamEditField.ValueChangedFcn = createCallbackFcn(app, @LarghezzamEditFieldValueChanged, true);
            app.LarghezzamEditField.Value = 0.01;
            app.SpessoremLabel = uilabel(app.GeometriadellapiastraPanel);
            app.SpessoremLabel.Text = 'Spessore [m]';
            app.SpessoremEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.SpessoremEditField.Limits = [2e-05 50];
            app.SpessoremEditField.ValueChangedFcn = createCallbackFcn(app, @SpessoremEditFieldValueChanged, true);
            app.SpessoremEditField.Value = 0.002;
            app.ValoriDerivatiPanel = uipanel(app.LeftPanel);
            app.ValoriDerivatiPanel.Title = 'Valori Derivati';
            app.CostantebetaVmCLabel = uilabel(app.ValoriDerivatiPanel);
            app.CostantebetaVmCLabel.Text = 'Costante beta [V*m/C]:';
            app.Areapiezoelettricom2Label = uilabel(app.ValoriDerivatiPanel);
            app.Areapiezoelettricom2Label.Text = 'Area piezoelettrico [m^2]:';
            app.CapacitstaticaCVLabel = uilabel(app.ValoriDerivatiPanel);
            app.CapacitstaticaCVLabel.Text = 'Capacità statica [C/V]: ';
            app.VelocitdipropagazionemsLabel = uilabel(app.ValoriDerivatiPanel);
            app.VelocitdipropagazionemsLabel.Text = 'Velocità di propagazione [m/s]:';
            app.Impedenzaacusticapiezokgm2sLabel = uilabel(app.ValoriDerivatiPanel);
            app.Impedenzaacusticapiezokgm2sLabel.Text = 'Impedenza acustica piezo[kg/(m^2 * s)]:';
            app.ImpedenzaacusticadellelementoindirezionezkgsLabel = uilabel(app.ValoriDerivatiPanel);
            app.ImpedenzaacusticadellelementoindirezionezkgsLabel.Text = 'Impedenza acustica dell''elemento in direzione z [kg/s]:';
            app.FrequenzadirisonanzaHzLabel = uilabel(app.ValoriDerivatiPanel);
            app.FrequenzadirisonanzaHzLabel.Text = 'Frequenza di risonanza [Hz]: ';
            app.CostantebetaValue = uilabel(app.ValoriDerivatiPanel);
            app.CostantebetaValue.Text = '';
            app.AreapiezoValue = uilabel(app.ValoriDerivatiPanel);
            app.AreapiezoValue.Text = '';
            app.CapacitaStaticaValue = uilabel(app.ValoriDerivatiPanel);
            app.CapacitaStaticaValue.Text = '';
            app.VelocitaDiPropagazioneValue = uilabel(app.ValoriDerivatiPanel);
            app.VelocitaDiPropagazioneValue.Text = '';
            app.ImpedenzaAcusticaPiezoValue = uilabel(app.ValoriDerivatiPanel);
            app.ImpedenzaAcusticaPiezoValue.Text = '';
            app.ImpedenzaAcusticaDirezionezValue = uilabel(app.ValoriDerivatiPanel);
            app.ImpedenzaAcusticaDirezionezValue.Text = '';
            app.FrequenzaDiRisonanzaValue = uilabel(app.ValoriDerivatiPanel);
            app.FrequenzaDiRisonanzaValue.Text = '';
            app.IntervalloconsideratoPanel = uipanel(app.LeftPanel);
            app.IntervalloconsideratoPanel.Title = 'Intervallo considerato';
            app.NumeroCampioniEditFieldLabel = uilabel(app.IntervalloconsideratoPanel);
            app.NumeroCampioniEditFieldLabel.Text = 'Numero Campioni';
            app.NumeroCampioniEditField = uieditfield(app.IntervalloconsideratoPanel, 'numeric');
            app.NumeroCampioniEditField.Limits = [100 100000];
            app.NumeroCampioniEditField.Value = 1000;
            app.FattoremoltiplicativoinferioreEditFieldLabel = uilabel(app.IntervalloconsideratoPanel);
            app.FattoremoltiplicativoinferioreEditFieldLabel.Text = 'Fattore moltiplicativo inferiore';
            app.FattoremoltiplicativoinferioreEditField = uieditfield(app.IntervalloconsideratoPanel, 'numeric');
            app.FattoremoltiplicativoinferioreEditField.Limits = [1e-05 1];
            app.FattoremoltiplicativoinferioreEditField.Value = 0.5;
            app.FattoremoltiplicativosuperioreLabel = uilabel(app.IntervalloconsideratoPanel);
            app.FattoremoltiplicativosuperioreLabel.Text = 'Fattore moltiplicativo superiore';
            app.FattoremoltiplicativosuperioreEditField = uieditfield(app.IntervalloconsideratoPanel, 'numeric');
            app.FattoremoltiplicativosuperioreEditField.Limits = [1 10];
            app.FattoremoltiplicativosuperioreEditField.Value = 1.5;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.UnaceramicaTab = uitab(app.TabGroup);
            app.UnaceramicaTab.Title = 'Una ceramica';
            app.TabGroup2 = uitabgroup(app.UnaceramicaTab);
            app.StudiosingolomaterialeTab = uitab(app.TabGroup2);
            app.StudiosingolomaterialeTab.Title = 'Studio singolo materiale';
            app.ConfrontotramaterialiTab = uitab(app.TabGroup2);
            app.ConfrontotramaterialiTab.Title = 'Confronto tra materiali';
            app.DueCeramicheTab = uitab(app.TabGroup);
            app.DueCeramicheTab.Title = 'Due Ceramiche';
            app.TrasduttorebandalargaTab = uitab(app.TabGroup);
            app.TrasduttorebandalargaTab.Title = 'Trasduttore banda larga';
            app.LangevinTab = uitab(app.TabGroup);
            app.LangevinTab.Title = 'Langevin';
            app.LangevinconconcentratoreTab = uitab(app.TabGroup);
            app.LangevinconconcentratoreTab.Title = 'Langevin con concentratore';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaccia_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end